classdef Trajectory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        wysokoschEditField              matlab.ui.control.NumericEditField
        wysokoschEditFieldLabel         matlab.ui.control.Label
        WysokoscpoczatkowahSlider       matlab.ui.control.Slider
        WysokoscpoczatkowahSliderLabel  matlab.ui.control.Label
        azymutEditField                 matlab.ui.control.NumericEditField
        azymutEditFieldLabel            matlab.ui.control.Label
        szerokoscgeoEditField           matlab.ui.control.NumericEditField
        szerokoscgeoEditFieldLabel      matlab.ui.control.Label
        gestoscpowietrzaEditField       matlab.ui.control.NumericEditField
        gestoscpowietrzaEditFieldLabel  matlab.ui.control.Label
        kaliberpociskuEditField         matlab.ui.control.NumericEditField
        kaliberpociskuEditFieldLabel    matlab.ui.control.Label
        MasapociskuEditField            matlab.ui.control.NumericEditField
        MasapociskuEditFieldLabel       matlab.ui.control.Label
        katwystrzaluSlider              matlab.ui.control.Slider
        katwystrzaluSliderLabel         matlab.ui.control.Label
        VelocitySlider                  matlab.ui.control.Slider
        VelocitySliderLabel             matlab.ui.control.Label
        katwystrzaluEditField           matlab.ui.control.NumericEditField
        katwystrzaluEditFieldLabel      matlab.ui.control.Label
        velocitymsEditField             matlab.ui.control.NumericEditField
        velocitymsEditFieldLabel        matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %  y( v0, theta0, ma, ca, rh, szer, azym, wysk)
            [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, app.katwystrzaluEditField.Value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,app.wysokoschEditField.Value);
            plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Button pushed function: StartButton
        function start(app, event)
          %  y( v0, theta0, ma, ca, rh, szer, azym, wysk)
            [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, app.katwystrzaluEditField.Value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,app.wysokoschEditField.Value);
            plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: velocitymsEditField
        function change_vel(app, event)
            value = app.velocitymsEditField.Value;
     [x, y, x1, y1, x2, y2] = missle_trajectory(value, app.katwystrzaluEditField.Value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,app.wysokoschEditField.Value);
            plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: katwystrzaluEditField
        function change_angle(app, event)
            value = app.katwystrzaluEditField.Value;
       [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,app.wysokoschEditField.Value);
            plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: katwystrzaluSlider
        function Angle_change_slider(app, event)
            value = app.katwystrzaluSlider.Value;
         [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,app.wysokoschEditField.Value);
            plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changing function: katwystrzaluSlider
        function Angle_changing_slider(app, event)
            changingValue = event.Value;
            [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, changingValue, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,app.wysokoschEditField.Value);
             plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: VelocitySlider
        function value_changed(app, event)
            value = app.VelocitySlider.Value;
              [x, y, x1, y1, x2, y2] = missle_trajectory(value, app.katwystrzaluEditField.Value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,app.wysokoschEditField.Value);
              plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changing function: VelocitySlider
        function value_vel_changing(app, event)
            changingValue = event.Value;
                 [x, y, x1, y1, x2, y2] = missle_trajectory(changingValue, app.katwystrzaluEditField.Value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,app.wysokoschEditField.Value);
      plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: WysokoscpoczatkowahSlider
        function wysokosc_zmiana(app, event)
            value = app.WysokoscpoczatkowahSlider.Value;
                 [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, app.katwystrzaluEditField.Value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,value);
         plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changing function: WysokoscpoczatkowahSlider
        function wysokosc_zmiana2(app, event)
            changingValue = event.Value;
             [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, app.katwystrzaluEditField.Value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,changingValue);
         plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: MasapociskuEditField
        function masa(app, event)
            value = app.MasapociskuEditField.Value;
             [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, app.katwystrzaluEditField.Value,value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,app.wysokoschEditField.Value);
         plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: kaliberpociskuEditField
        function kaliber(app, event)
            value = app.kaliberpociskuEditField.Value;
             [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, app.katwystrzaluEditField.Value, app.MasapociskuEditField.Value,value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,app.wysokoschEditField.Value);
         plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: gestoscpowietrzaEditField
        function gestosc(app, event)
            value = app.gestoscpowietrzaEditField.Value;
             [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, app.katwystrzaluEditField.Value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,app.wysokoschEditField.Value);
         plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: szerokoscgeoEditField
        function szerokosc(app, event)
            value = app.szerokoscgeoEditField.Value;
             [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, app.katwystrzaluEditField.Value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,value,app.azymutEditField.Value,app.wysokoschEditField.Value);
         plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: azymutEditField
        function azymut(app, event)
            value = app.azymutEditField.Value;
             [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, app.katwystrzaluEditField.Value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,value,app.wysokoschEditField.Value);
         plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: wysokoschEditField
        function wys(app, event)
            value = app.wysokoschEditField.Value;
             [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, app.katwystrzaluEditField.Value, app.MasapociskuEditField.Value,app.kaliberpociskuEditField.Value,app.gestoscpowietrzaEditField.Value,app.szerokoscgeoEditField.Value,app.azymutEditField.Value,value);
         plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajectory of 35 mm missle')
            xlabel(app.UIAxes, 'X range (m)')
            ylabel(app.UIAxes, 'Y range (m)')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.StartButton.Text = 'Start';
            app.velocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.velocitymsEditFieldLabel.Text = 'velocity m/s';
            app.velocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.velocitymsEditField.Limits = [0 2000];
            app.velocitymsEditField.ValueChangedFcn = createCallbackFcn(app, @change_vel, true);
            app.velocitymsEditField.Value = 30;
            app.katwystrzaluEditFieldLabel = uilabel(app.UIFigure);
            app.katwystrzaluEditFieldLabel.Text = {'kat wystrzalu'; ''};
            app.katwystrzaluEditField = uieditfield(app.UIFigure, 'numeric');
            app.katwystrzaluEditField.Limits = [0 90];
            app.katwystrzaluEditField.ValueChangedFcn = createCallbackFcn(app, @change_angle, true);
            app.katwystrzaluEditField.Value = 20;
            app.VelocitySliderLabel = uilabel(app.UIFigure);
            app.VelocitySliderLabel.Text = {'Velocity'; ''};
            app.VelocitySlider = uislider(app.UIFigure);
            app.VelocitySlider.Limits = [0 2000];
            app.VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @value_changed, true);
            app.VelocitySlider.ValueChangingFcn = createCallbackFcn(app, @value_vel_changing, true);
            app.VelocitySlider.Value = 30;
            app.katwystrzaluSliderLabel = uilabel(app.UIFigure);
            app.katwystrzaluSliderLabel.Text = 'kat wystrzalu';
            app.katwystrzaluSlider = uislider(app.UIFigure);
            app.katwystrzaluSlider.Limits = [0 180];
            app.katwystrzaluSlider.ValueChangedFcn = createCallbackFcn(app, @Angle_change_slider, true);
            app.katwystrzaluSlider.ValueChangingFcn = createCallbackFcn(app, @Angle_changing_slider, true);
            app.katwystrzaluSlider.Value = 30;
            app.MasapociskuEditFieldLabel = uilabel(app.UIFigure);
            app.MasapociskuEditFieldLabel.Text = 'Masa pocisku';
            app.MasapociskuEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasapociskuEditField.ValueChangedFcn = createCallbackFcn(app, @masa, true);
            app.MasapociskuEditField.Value = 1;
            app.kaliberpociskuEditFieldLabel = uilabel(app.UIFigure);
            app.kaliberpociskuEditFieldLabel.Text = 'kaliber pocisku';
            app.kaliberpociskuEditField = uieditfield(app.UIFigure, 'numeric');
            app.kaliberpociskuEditField.ValueChangedFcn = createCallbackFcn(app, @kaliber, true);
            app.kaliberpociskuEditField.Value = 0.35;
            app.gestoscpowietrzaEditFieldLabel = uilabel(app.UIFigure);
            app.gestoscpowietrzaEditFieldLabel.Text = 'gestosc powietrza';
            app.gestoscpowietrzaEditField = uieditfield(app.UIFigure, 'numeric');
            app.gestoscpowietrzaEditField.ValueChangedFcn = createCallbackFcn(app, @gestosc, true);
            app.gestoscpowietrzaEditField.Value = 1.2;
            app.szerokoscgeoEditFieldLabel = uilabel(app.UIFigure);
            app.szerokoscgeoEditFieldLabel.Text = 'szerokosc geo';
            app.szerokoscgeoEditField = uieditfield(app.UIFigure, 'numeric');
            app.szerokoscgeoEditField.ValueChangedFcn = createCallbackFcn(app, @szerokosc, true);
            app.szerokoscgeoEditField.Value = 50;
            app.azymutEditFieldLabel = uilabel(app.UIFigure);
            app.azymutEditFieldLabel.Text = 'azymut';
            app.azymutEditField = uieditfield(app.UIFigure, 'numeric');
            app.azymutEditField.ValueChangedFcn = createCallbackFcn(app, @azymut, true);
            app.azymutEditField.Value = 40;
            app.WysokoscpoczatkowahSliderLabel = uilabel(app.UIFigure);
            app.WysokoscpoczatkowahSliderLabel.Text = 'Wysokosc poczatkowa h';
            app.WysokoscpoczatkowahSlider = uislider(app.UIFigure);
            app.WysokoscpoczatkowahSlider.Limits = [0 1000];
            app.WysokoscpoczatkowahSlider.ValueChangedFcn = createCallbackFcn(app, @wysokosc_zmiana, true);
            app.WysokoscpoczatkowahSlider.ValueChangingFcn = createCallbackFcn(app, @wysokosc_zmiana2, true);
            app.wysokoschEditFieldLabel = uilabel(app.UIFigure);
            app.wysokoschEditFieldLabel.Text = 'wysokosc h';
            app.wysokoschEditField = uieditfield(app.UIFigure, 'numeric');
            app.wysokoschEditField.ValueChangedFcn = createCallbackFcn(app, @wys, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trajectory

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end